classdef interfaceapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AboutTab                      matlab.ui.container.Tab
        MillerBladeStructLabel        matlab.ui.control.Label
        MillerBladeStructDescription  matlab.ui.control.TextArea
        BladeStructTab                matlab.ui.container.Tab
        BladeStructLabel              matlab.ui.control.Label
        PropertyGeneratorTab          matlab.ui.container.Tab
        PropertyGeneratorLabel        matlab.ui.control.Label
        OptionsPanel                  matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        SimpleComplexSwitch           matlab.ui.control.Switch
        SwitchLabel                   matlab.ui.control.Label
        TwistSwitch                   matlab.ui.control.Switch
        Label_2                       matlab.ui.control.Label
        ChordSwitch                   matlab.ui.control.Switch
        Label_3                       matlab.ui.control.Label
        SaveSwitch                    matlab.ui.control.Switch
        GeneratePropertiesPanel       matlab.ui.container.Panel
        AirfoilEditFieldLabel         matlab.ui.control.Label
        AirfoilEditField              matlab.ui.control.EditField
        BrowseAirfoilButton           matlab.ui.control.Button
        BrowseTwistButton             matlab.ui.control.Button
        TwistDistributionEditFieldLabel  matlab.ui.control.Label
        TwistDistributionEditField    matlab.ui.control.EditField
        BrowseChordButton             matlab.ui.control.Button
        ChordDistributionEditFieldLabel  matlab.ui.control.Label
        ChordDistributionEditField    matlab.ui.control.EditField
        BrowseNegativeSpaceButton     matlab.ui.control.Button
        NegativeSpaceEditFieldLabel   matlab.ui.control.Label
        NegativeSpaceEditField        matlab.ui.control.EditField
        BrowseSaveAirfoilButton       matlab.ui.control.Button
        PropertyGeneratorOutputEditFieldLabel  matlab.ui.control.Label
        PropertyGeneratorOutputEditField  matlab.ui.control.EditField
        GeneratePropertiesButton      matlab.ui.control.Button
        GeneratePropertiesTextArea    matlab.ui.control.TextArea
        XTurbBackendTab               matlab.ui.container.Tab
        XTurbBackendLabel             matlab.ui.control.Label
        ConverterPanel                matlab.ui.container.Panel
        BrowseXTurb_OutputdatButton   matlab.ui.control.Button
        XTurb_OutputdatLabel          matlab.ui.control.Label
        XTurb_OutputdatEditField      matlab.ui.control.EditField
    end

    % User-written properties and methods
    properties(Access = public)
        
        % Property generator
        propgenmode;
        twistmode;
        chordmode;
        savemode;
        
        % File name
        airfoilname;
        twistname;
        chordname;
        negativename;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize certain properties
            app.propgenmode = "Complex Geometry";
            app.twistmode = "User-defined Twist";
            app.chordmode = "User-defined Chord";
            app.savemode = "Save Properties to File";
        end

        % Value changed function: SimpleComplexSwitch
        function SimpleComplexSwitchValueChanged(app, event)
            value = app.SimpleComplexSwitch.Value;
            app.propgenmode = value;
            
            if value == "Solid Geometry"
                app.NegativeSpaceEditField.Visible = 'off';
                %                 app.NegativeSpaceEditField.Value = "";
            elseif value == "Complex Geometry"
                app.NegativeSpaceEditField.Visible = 'on';
            end
        end

        % Value changed function: TwistSwitch
        function TwistSwitchValueChanged(app, event)
            value = app.TwistSwitch.Value;
            app.twistmode = value;
            
            if value == "No Twist"
                app.TwistDistributionEditField.Visible = 'off';
                %                 app.TwistDistributionEditField.Value = "";
            elseif value == "User-defined Twist"
                app.TwistDistributionEditField.Visible = 'on';
            end
        end

        % Value changed function: ChordSwitch
        function ChordSwitchValueChanged(app, event)
            value = app.ChordSwitch.Value;
            app.chordmode = value;
            
            if value == "Chord = 1"
                app.ChordDistributionEditField.Visible = 'off';
                %                 app.ChordDistributionEditField.Value = "";
            elseif value == "User-defined Chord"
                app.ChordDistributionEditField.Visible = 'on';
            end
        end

        % Value changed function: SaveSwitch
        function SaveSwitchValueChanged(app, event)
            value = app.SaveSwitch.Value;
            app.savemode = value;
            
            if value == "Output Only"
                app.PropertyGeneratorOutputEditField.Visible = 'off';
                %                 app.PropertyGeneratorOutputEditField.Value = "";
            elseif value == "Save Properties to File"
                app.PropertyGeneratorOutputEditField.Visible = 'on';
            end
        end

        % Button pushed function: BrowseAirfoilButton
        function BrowseAirfoilButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.AirfoilEditField.Value =...
                strip(strcat(erase(folder, pwd), file), 'left', '/');
        end

        % Button pushed function: BrowseTwistButton
        function BrowseTwistButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.TwistDistributionEditField.Value =...
                strip(strcat(erase(folder, pwd), file), 'left', '/');
        end

        % Button pushed function: BrowseChordButton
        function BrowseChordButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.ChordDistributionEditField.Value =...
                strip(strcat(erase(folder, pwd), file), 'left', '/');
        end

        % Button pushed function: BrowseNegativeSpaceButton
        function BrowseNegativeSpaceButtonPushed(app, event)
            % write browse path to the edit field
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.NegativeSpaceEditField.Value =...
                strip(strcat(erase(dir, pwd), "/"), 'left', '/');
        end

        % Button pushed function: BrowseSaveAirfoilButton
        function BrowseSaveAirfoilButtonPushed(app, event)
            % write browse path to the edit field
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.PropertyGeneratorOutputEditField.Value =...
                strip(strcat(erase(dir, pwd), "/"), 'left', '/');
        end

        % Button pushed function: GeneratePropertiesButton
        function GeneratePropertiesButtonPushed(app, event)
            % Process file paths
            airfoilfp = strcat(pwd, "/",...
                erase(app.AirfoilEditField.Value, pwd));
            [fullpath, app.airfoilname, ext] = fileparts(strcat(airfoilfp));
            twistfp = strcat(pwd, "/",...
                erase(app.TwistDistributionEditField.Value, pwd));
            chordfp = strcat(pwd, "/",...
                erase(app.ChordDistributionEditField.Value, pwd));
            if app.propgenmode == "Solid Geometry"
                app.negativename = "Solid";
            elseif app.propgenmode == "Complex Geometry"
                app.negativename = "Complex";
            end
            
            % Define input values, alter file name components
            af = app.AirfoilEditField.Value; % airfoil file path
            naf = app.NegativeSpaceEditField.Value; % negative space dir
            if app.chordmode == "Chord = 1" % chord values
                c = 1;
                app.chordname = "Chord = 1";
            elseif app.chordmode == "User-defined Chord"
                c = app.ChordDistributionEditField.Value;
                [fullpath, app.chordname, ext] = fileparts(strcat(chordfp));
            end
            if app.twistmode == "No Twist" % twist values
                beta = 0;
                app.twistname = "No Twist"
            elseif app.twistmode == "User-defined Twist"
                beta = app.TwistDistributionEditField.Value;
                [fullpath, app.twistname, ext] = fileparts(strcat(twistfp));
            end
            saveloc = app.PropertyGeneratorOutputEditField.Value; % save dir
            
            % Generate properties
            if app.propgenmode == "Solid Geometry"
                prop = interface.simplepropgen(af, c, beta);
            elseif app.propgenmode == "Complex Geometry"
                prop = interface.afpropgen(af, naf, c, beta);
            end
            
            % Format generated properties
            out = cellstr(strcat("[ ", prop(:,1), "    ",...
                prop(:,2), "    ", prop(:,3), "    ",...
                prop(:,4), "    ", prop(:,5),...
                "    ", prop(:,6)," ]"));
            
            % Create file name
            filename = strcat(app.airfoilname, "_", app.negativename,...
                "_", app.twistname, "_", app.chordname);
            filename = strcat(saveloc, filename, ".csv");
            
            % GUI outputs
            if app.savemode == "Output Only"
                app.GeneratePropertiesTextArea.Value =...
                    "Working...";
                pause(0.25);
                app.GeneratePropertiesTextArea.Value = out;
            elseif app.savemode == "Save Properties to File"
                app.GeneratePropertiesTextArea.Value =...
                    "Working...";
                pause(0.25);
                writecell(cellstr(prop), filename);
                app.GeneratePropertiesTextArea.Value =...
                    ["Saved!", string(filename)];
            end
        end

        % Button pushed function: BrowseXTurb_OutputdatButton
        function BrowseXTurb_OutputdatButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.XTurb_OutputdatEditField.Value =...
                strip(strcat(erase(folder, pwd), file), 'left', '/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.MillerBladeStructLabel = uilabel(app.AboutTab);
            app.MillerBladeStructLabel.Text = 'Miller-Blade-Struct v0.1.0';
            app.MillerBladeStructDescription = uitextarea(app.AboutTab);
            app.MillerBladeStructDescription.Value = {''; 'Miller Lab code for turbine blade structures. Currently capable of calculating deflections, eigenmodes, and blade properties.'; ''; '----------------------------------------------------------------------------------------'; ''; 'Written by: Sean Wang (sjw5651@psu.edu)'; ''; '----------------------------------------------------------------------------------------'; ''; 'This is the about menu. There is currently nothing here.'};
            app.BladeStructTab = uitab(app.TabGroup);
            app.BladeStructTab.Title = 'Blade-Struct';
            app.BladeStructLabel = uilabel(app.BladeStructTab);
            app.BladeStructLabel.Text = 'Blade-Struct';
            app.PropertyGeneratorTab = uitab(app.TabGroup);
            app.PropertyGeneratorTab.Title = 'Property-Generator';
            app.PropertyGeneratorLabel = uilabel(app.PropertyGeneratorTab);
            app.PropertyGeneratorLabel.Text = 'Property-Generator';
            app.OptionsPanel = uipanel(app.PropertyGeneratorTab);
            app.OptionsPanel.Title = 'Options';
            app.Label = uilabel(app.OptionsPanel);
            app.Label.Text = '';
            app.SimpleComplexSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.SimpleComplexSwitch.Items = {'Complex Geometry', 'Solid Geometry'};
            app.SimpleComplexSwitch.ValueChangedFcn = createCallbackFcn(app, @SimpleComplexSwitchValueChanged, true);
            app.SimpleComplexSwitch.Value = 'Complex Geometry';
            app.SwitchLabel = uilabel(app.OptionsPanel);
            app.SwitchLabel.Text = '';
            app.TwistSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.TwistSwitch.Items = {'User-defined Twist', 'No Twist'};
            app.TwistSwitch.ValueChangedFcn = createCallbackFcn(app, @TwistSwitchValueChanged, true);
            app.TwistSwitch.Value = 'User-defined Twist';
            app.Label_2 = uilabel(app.OptionsPanel);
            app.Label_2.Text = '';
            app.ChordSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ChordSwitch.Items = {'User-defined Chord', 'Chord = 1'};
            app.ChordSwitch.ValueChangedFcn = createCallbackFcn(app, @ChordSwitchValueChanged, true);
            app.ChordSwitch.Value = 'User-defined Chord';
            app.Label_3 = uilabel(app.OptionsPanel);
            app.Label_3.Text = '';
            app.SaveSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.SaveSwitch.Items = {'Save Properties to File', 'Output Only'};
            app.SaveSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveSwitchValueChanged, true);
            app.SaveSwitch.Value = 'Save Properties to File';
            app.GeneratePropertiesPanel = uipanel(app.PropertyGeneratorTab);
            app.GeneratePropertiesPanel.Title = 'Generate Properties';
            app.AirfoilEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.AirfoilEditFieldLabel.Text = 'Airfoil';
            app.AirfoilEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseAirfoilButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseAirfoilButtonPushed, true);
            app.BrowseAirfoilButton.Text = 'Browse';
            app.BrowseTwistButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseTwistButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTwistButtonPushed, true);
            app.BrowseTwistButton.Text = 'Browse';
            app.TwistDistributionEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.TwistDistributionEditFieldLabel.Text = 'Twist Distribution';
            app.TwistDistributionEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseChordButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseChordButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseChordButtonPushed, true);
            app.BrowseChordButton.Text = 'Browse';
            app.ChordDistributionEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.ChordDistributionEditFieldLabel.Text = 'Chord Distribution';
            app.ChordDistributionEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseNegativeSpaceButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseNegativeSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseNegativeSpaceButtonPushed, true);
            app.BrowseNegativeSpaceButton.Text = 'Browse';
            app.NegativeSpaceEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.NegativeSpaceEditFieldLabel.Text = 'Negative Space';
            app.NegativeSpaceEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseSaveAirfoilButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseSaveAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSaveAirfoilButtonPushed, true);
            app.BrowseSaveAirfoilButton.Text = 'Browse';
            app.PropertyGeneratorOutputEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.PropertyGeneratorOutputEditFieldLabel.Text = 'Property Generator Output';
            app.PropertyGeneratorOutputEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.GeneratePropertiesButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.GeneratePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePropertiesButtonPushed, true);
            app.GeneratePropertiesButton.Text = {'Generate'; 'Properties'};
            app.GeneratePropertiesTextArea = uitextarea(app.GeneratePropertiesPanel);
            app.XTurbBackendTab = uitab(app.TabGroup);
            app.XTurbBackendTab.Title = 'XTurb-Backend';
            app.XTurbBackendLabel = uilabel(app.XTurbBackendTab);
            app.XTurbBackendLabel.Text = 'XTurb-Backend';
            app.ConverterPanel = uipanel(app.XTurbBackendTab);
            app.ConverterPanel.Title = 'Converter';
            app.BrowseXTurb_OutputdatButton = uibutton(app.ConverterPanel, 'push');
            app.BrowseXTurb_OutputdatButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseXTurb_OutputdatButtonPushed, true);
            app.BrowseXTurb_OutputdatButton.Text = 'Browse';
            app.XTurb_OutputdatLabel = uilabel(app.ConverterPanel);
            app.XTurb_OutputdatLabel.Text = 'XTurb_Output[ ].dat';
            app.XTurb_OutputdatEditField = uieditfield(app.ConverterPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaceapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end